classdef demoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DomainTab                       matlab.ui.container.Tab
        UIAxesExampleSolution           matlab.ui.control.UIAxes
        DomainConfigurationPanel        matlab.ui.container.Panel
        domainDropDownLabel             matlab.ui.control.Label
        domainDropDown                  matlab.ui.control.DropDown
        loadeddomainLabel               matlab.ui.control.Label
        DOFDropDownLabel                matlab.ui.control.Label
        DOFDropDown                     matlab.ui.control.DropDown
        DescriptionTextAreaLabel        matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        UIAxesHighFitnessSolution       matlab.ui.control.UIAxes
        IlluminationTab                 matlab.ui.container.Tab
        QDConfigurationPanel            matlab.ui.container.Panel
        archiveDropDownLabel            matlab.ui.control.Label
        archiveDropDown                 matlab.ui.control.DropDown
        nogenerationsEditFieldLabel     matlab.ui.control.Label
        nogenerationsEditField          matlab.ui.control.NumericEditField
        featurexaxisDropDownLabel       matlab.ui.control.Label
        featurexaxisDropDown            matlab.ui.control.DropDown
        featureyaxisDropDownLabel       matlab.ui.control.Label
        featureyaxisDropDown            matlab.ui.control.DropDown
        surrogateassistanceCheckBox     matlab.ui.control.CheckBox
        resolutionSliderLabel           matlab.ui.control.Label
        resolutionSlider                matlab.ui.control.Slider
        mutationstrengthLabel           matlab.ui.control.Label
        mutationstrengthSlider          matlab.ui.control.Slider
        QDcommandsPanel                 matlab.ui.container.Panel
        RunQDButton                     matlab.ui.control.Button
        RedrawMapButton                 matlab.ui.control.Button
        UIAxesPhenotypicMap             matlab.ui.control.UIAxes
        UIAxesSelectionHistory          matlab.ui.control.UIAxes
        UIAxesTrueFitness               matlab.ui.control.UIAxes
        UIAxesSimilaritySpace           matlab.ui.control.UIAxes
        UIAxesMeanFitness               matlab.ui.control.UIAxes
        UIAxesMeanDrift                 matlab.ui.control.UIAxes
        VisualizeDriftCheckBox          matlab.ui.control.CheckBox
        UIAxesAcquisition               matlab.ui.control.UIAxes
        UserSelectionPressurePanel      matlab.ui.container.Panel
        SliderLabel                     matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        ExplicitTab                     matlab.ui.container.Tab
        UIAxesExplicitPhenotypesLarge   matlab.ui.control.UIAxes
        AnalysisSelectionTab            matlab.ui.container.Tab
        AnalysisPanel                   matlab.ui.container.Panel
        GeneticPrototypingButton        matlab.ui.control.Button
        noprototypesSliderLabel         matlab.ui.control.Label
        noprototypesSlider              matlab.ui.control.Slider
        UIAxesClustersInSimilarity      matlab.ui.control.UIAxes
        UserSelectionPanel              matlab.ui.container.Panel
        AddSelectionButton              matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        consoleTextAreaLabel            matlab.ui.control.Label
        consoleTextArea                 matlab.ui.control.TextArea
        SelectionIterationListBoxLabel  matlab.ui.control.Label
        SelectionIterationListBox       matlab.ui.control.ListBox
        UIAxesPrototypesForSelection    matlab.ui.control.UIAxes
        UIAxesSelectioNHistory2         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        p % app.ALGORITHM configuration
        d % Domain configuration
        map % Current map object
        classification % Classification
        selectedPrototypes % Keep selection saved here
        constraints % User constraints
        iter % PRODUQD iteration
        fitfun % Function pointer to current fitness function
        visDriftSimspace % Check whether to visualize user selection drift in similarity space
        DOMAIN % Domain name
        ALGORITHM % Algorithm name
        simspace % Temporarily hold similarity space
        selectionLabels % Temporarily hold (visually) selected prototypes
        selectedIter % used to remove old selection constraints
        sa % Surrogate assistance flag
    end
    
    methods (Access = private)
        
        function setDomain(varargin)
            % Load domain
            app = varargin{1};
            app.DOMAIN = varargin{2};
            rmpath(genpath('domain')); addpath(genpath(['domain/' app.DOMAIN]));
            dof = 'default'; if nargin > 2; dof = varargin{3}; end
            app.d{app.iter} = domain(dof);
            app.loadeddomainLabel.Text = ['DOMAIN: ' app.DOMAIN];
            
            % Set features to GUI
            app.featurexaxisDropDown.Items = app.d{app.iter}.featureLabels;
            app.featureyaxisDropDown.Items = app.d{app.iter}.featureLabels;
            app.featurexaxisDropDown.Value = app.featurexaxisDropDown.Items{1};
            app.featureyaxisDropDown.Value = app.featureyaxisDropDown.Items{2};
            
            app.d{app.iter}.penaltyWeight = app.Slider.Value;
            app.visDriftSimspace = true;
            
            % Show example solution
            app.UIAxesExampleSolution.cla;
            randInd = randn(1,app.d{app.iter}.dof);
            showPhenotype(app.UIAxesExampleSolution,app.d{app.iter},randInd);
            phenotype = getPhenotype(randInd,app.d{app.iter});
            fitness = app.d{app.iter}.fitfun(randInd,phenotype);
            title(app.UIAxesExampleSolution,['Random Fitness Example: ' num2str(fitness)]);
            axis(app.UIAxesExampleSolution,'equal');axis(app.UIAxesExampleSolution,[-1 1 -1 1]);
            
            % Load description and show high and low fitness example if
            % available from domain.m
            app.UIAxesHighFitnessSolution.cla;
            if isfield(app.d{app.iter},'sampleHighFit')
                showPhenotype(app.UIAxesHighFitnessSolution,app.d{app.iter},app.d{app.iter}.sampleHighFit);
                phenotype = getPhenotype(app.d{app.iter}.sampleHighFit,app.d{app.iter});
                fitness = app.d{app.iter}.fitfun(app.d{app.iter}.sampleHighFit,phenotype);
                title(app.UIAxesHighFitnessSolution,['High Fitness Example: ' num2str(fitness)]);
                axis(app.UIAxesHighFitnessSolution,'equal');axis(app.UIAxesHighFitnessSolution,[-1 1 -1 1]);
            end
            
            if isfield(app.d{app.iter},'description')
                app.DescriptionTextArea.Value = app.d{app.iter}.description;
            end
            
        end
        
        function setQD(app,ALGORITHM)
            rmpath('QD/grid'); rmpath('QD/voronoi'); addpath(['QD/' ALGORITHM]);
            app.ALGORITHM = ALGORITHM;
            % Load configuration
            if ~isempty(app.p) && isfield(app.p{app.iter},'nGens')
                nGens = app.p{app.iter}.nGens;
                app.p{app.iter} = defaultParamSet(4);
                app.p{app.iter}.nGens = nGens;
            else
                app.p{app.iter} = defaultParamSet(4);
            end
            
            app.nogenerationsEditField.Value = app.p{app.iter}.nGens;
            if strcmp(app.ALGORITHM,'grid')
                app.resolutionSlider.Limits = [5 50];
                app.resolutionSlider.Value = 10;
            elseif strcmp(app.ALGORITHM,'voronoi')
                app.resolutionSlider.Limits = [0.001 0.1];
                app.resolutionSlider.Value = 0.01;
            end
            
            app.resolutionSlider.Value = app.p{app.iter}.featureResolution(1);
            app.mutationstrengthSlider.Value = app.p{app.iter}.mutSigma;
        end
        
        function showPhenotypesInArchive(app,figHandle)
            cla(figHandle);
            placement = [];
            if strcmp(app.archiveDropDown.Value,'voronoi')
                genes = app.map{app.iter}.genes;
                spacer = 15*app.d{app.iter}.spacer;
                placement = spacer.* ([0 1] + [1 -1].*app.map{app.iter}.features(:,app.d{app.iter}.selectedFeatures));
            elseif strcmp(app.archiveDropDown.Value,'grid')
                spacer = app.d{app.iter}.spacer;
                for i=1:length(app.map{app.iter}.edges{1})-1
                    for j=1:length(app.map{app.iter}.edges{2})-1
                        placement((j-1)*(length(app.map{app.iter}.edges{2})-1)+i,:) = [i*spacer (length(app.map{app.iter}.edges{2})-1-j)*spacer];
                    end
                end
                genes = reshape(app.map{app.iter}.genes,size(app.map{app.iter}.genes,1)*size(app.map{app.iter}.genes,2),[]);
                
            end
            showPhenotype(figHandle,app.d{app.iter},genes,placement);
            figHandle.XLabel.String = app.d{app.iter}.featureLabels(app.d{app.iter}.selectedFeatures(1));
            figHandle.YLabel.String = app.d{app.iter}.featureLabels(app.d{app.iter}.selectedFeatures(2));
            figHandle.XTick = [];
            figHandle.YTick = [];
            axis(figHandle,spacer*[app.UIAxesPhenotypicMap.XLim app.UIAxesPhenotypicMap.YLim]);
            axis(figHandle,'equal');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'));
            app.iter = 1;
            app.selectedPrototypes = {};
            app.constraints = {};
            app.d = {};
            app.p = {};
            app.DOMAIN = app.domainDropDown.Value; %npoly_ffd npoly dropwave shubert4
            DOF = str2double(app.DOFDropDown.Value);
            setDomain(app, app.DOMAIN, DOF);
            app.ALGORITHM = app.archiveDropDown.Value;
            setQD(app,app.ALGORITHM);            
            app.visDriftSimspace = app.VisualizeDriftCheckBox.Value;
        end

        % Button pushed function: RunQDButton
        function RunQDButtonPushed(app, event)
            disp(['Illuminating iteration: ' int2str(app.iter)]);
            
            constraints = app.constraints; if length(app.constraints) >= app.iter; constraints = app.constraints(1:app.iter); end  
            
            app.p{app.iter}.infill = infillParamSet;
            if app.iter > 1
                app.p{app.iter}.infill.nTotalSamples = app.p{app.iter}.infill.nTotalSamples + 30;
                app.p{app.iter}.numInitSamples = size(app.surrogate.trainInput,1);
            end            
            [map,fitnessFunction] = initialize(constraints,app.d{app.iter},app.p{app.iter},app.sa);
            if app.sa
                % Either run Bayesian Optimization with surrogate model 
                app.map{app.iter} = sail(map,fitnessFunction,app.p{app.iter},app.d{app.iter},app.UIAxesAcquisition, app.UIAxesPhenotypicMap,app.UIAxesMeanFitness,app.UIAxesMeanDrift);                
                %fitnessFunction
                predictedOptima = reshape(app.map{app.iter}.genes,[],app.d{app.iter}.dof);
                trueFitness = fitnessFunction(predictedOptima,app.d{app.iter}.fitfun,0);
                trueMap = app.map{app.iter};
                trueMap.fitness = reshape(trueFitness,size(map.fitness,1),size(map.fitness,2));
                viewMap(trueMap,app.d{app.iter},app.UIAxesTrueFitness);
                title(app.UIAxesTrueFitness,'True Fitness');    
            else
                % or run pure QD without a surrogate model
                app.map{app.iter} = illuminate(map,fitnessFunction,app.p{app.iter},app.d{app.iter},app.UIAxesPhenotypicMap,app.UIAxesMeanFitness,app.UIAxesMeanDrift);
            end
            
            % Show data
            app.showPhenotypesInArchive(app.UIAxesExplicitPhenotypesLarge);            
        end

        % Value changed function: domainDropDown
        function domainDropDownValueChanged(app, event)
            dof = app.domainDropDown.Value;
            setDomain(app, app.domainDropDown.Value, dof);
        end

        % Value changed function: DOFDropDown
        function DOFDropDownValueChanged(app, event)
            app.setDomain(app.domainDropDown.Value,str2double(app.DOFDropDown.Value));
        end

        % Value changed function: archiveDropDown
        function archiveDropDownValueChanged(app, event)
            value = app.archiveDropDown.Value;
            setQD(app,value);
        end

        % Value changed function: resolutionSlider
        function resolutionSliderValueChanged(app, event)
            value = app.resolutionSlider.Value;
            if strcmp(app.ALGORITHM,'grid')
                app.p{app.iter}.featureResolution = [floor(value) floor(value)];
            elseif strcmp(app.ALGORITHM,'voronoi')
                app.p{app.iter}.featureResolution = value;
            end
        end

        % Value changed function: nogenerationsEditField
        function nogenerationsEditFieldValueChanged(app, event)
            value = app.nogenerationsEditField.Value;
            app.p{app.iter}.nGens = value;
            app.nogenerationsEditField.Value = value;
        end

        % Value changed function: featurexaxisDropDown
        function featurexaxisDropDownValueChanged(app, event)
            value = app.featurexaxisDropDown.Value;
            col = find(strcmp(value,app.d{app.iter}.featureLabels));
            app.d{app.iter}.selectedFeatures(1) = col;
        end

        % Value changed function: featureyaxisDropDown
        function featureyaxisDropDownValueChanged(app, event)
            value = app.featureyaxisDropDown.Value;
            col = find(strcmp(value,app.d{app.iter}.featureLabels));
            app.d{app.iter}.selectedFeatures(2) = col;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.d{app.iter}.penaltyWeight = value;
        end

        % Value changed function: mutationstrengthSlider
        function mutationstrengthSliderValueChanged(app, event)
            value = app.mutationstrengthSlider.Value;
            app.p{app.iter}.mutSigma = value;
        end

        % Value changed function: VisualizeDriftCheckBox
        function VisualizeDriftCheckBoxValueChanged(app, event)
            value = app.VisualizeDriftCheckBox.Value;
            app.visDriftSimspace = value;
        end

        % Button pushed function: RedrawMapButton
        function RedrawMapButtonPushed(app, event)
            app.UIAxesPhenotypicMap.cla;
            disp('TODO: UPDATE MAP!! BUT KEEP ORIGINAL')
            genes = reshape(app.map{app.iter}.genes,[],size(app.map{app.iter}.genes,ndims(app.map{app.iter}.genes)));
            fitness = reshape(app.map{app.iter}.fitness,size(app.map{app.iter}.fitness,1)*size(app.map{app.iter}.fitness,2),[]);
            features = reshape(app.map{app.iter}.features,[],size(app.map{app.iter}.features,ndims(app.map{app.iter}.features)));
            
            genes               = genes(all(~isnan(genes)'),:);
            fitness             = fitness(~isnan(fitness));
            features            = features(all(~isnan(features)'),:);
            currentMap          = createMap(app.d{app.iter}, app.p{app.iter});
            [replaced, replacement, features] = nicheCompete(genes, fitness, [], currentMap, app.d{app.iter}, app.p{app.iter},features);
            currentMap          = updateMap(replaced,replacement,currentMap,fitness,genes,[],features,[]);
            
            viewMap(currentMap,app.d{app.iter},app.UIAxesPhenotypicMap);% title('Fitness'); caxis(d.fitnessRange);
        end

        % Button pushed function: GeneticPrototypingButton
        function GeneticPrototypingButtonPushed(app, event)
            if isempty(app.simspace)
                [app.classification{app.iter}, app.simspace] = extractClasses(app.map{app.iter}.genes,[],'kmedoids',floor(app.noprototypesSlider.Value));
            else
                app.classification{app.iter} = extractClasses(app.map{app.iter}.genes,app.simspace,'kmedoids',floor(app.noprototypesSlider.Value));
            end
            % Show classes in similarity space
            viewClasses(app.map{app.iter}.genes,app.classification{app.iter},app.UIAxesClustersInSimilarity);
            % Show prototypes
            % Row and col placement
            numRows = ceil( size(app.classification{app.iter}.protoX,1).^(2/3) );
            for i=1:size(app.classification{app.iter}.protoX,1)
                placement(i,:) = [mod(i-1,numRows) floor((i-1)/numRows)]*app.d{app.iter}.spacer;
            end
            app.UIAxesPrototypesForSelection.cla;
            showPhenotype(app.UIAxesPrototypesForSelection,app.d{app.iter},app.classification{app.iter}.protoX,placement);
            axis(app.UIAxesPrototypesForSelection, 'equal');
            app.AddSelectionButton.Enable = true;
            
        end

        % Button pushed function: AddSelectionButton
        function AddSelectionButtonPushed(app, event)
            % Save selection
            if isempty(app.selectedPrototypes)
                app.selectedPrototypes(app.iter) = {find(app.selectionLabels==1)};
            else
                app.selectedPrototypes{app.iter} = find(app.selectionLabels==1);
            end
            disp(app.selectedPrototypes(app.iter))
            
            % Capture user selection by training User Decision Hypersurface Model (UDHM) based on selected prototypes
            % The UDHM will be able to provide a drift metric that can be
            % used to e.g. add penalties to the fitness function
            if length(app.selectedPrototypes) >= app.iter && ~isempty(app.selectedPrototypes{app.iter})
                app.constraints{app.iter} = setConstraints(app.classification{app.iter},app.selectedPrototypes{app.iter},[],'class');
                app.iter = app.iter + 1;
                % Initialize configuration for this iteration
                app.d{app.iter} = app.d{app.iter-1};
                app.p{app.iter} = app.p{app.iter-1};
                
                % GUi output and changes
                app.consoleTextArea.Value = 'Selection model trained.';
                app.AddSelectionButton.Enable = false;
                app.SelectionIterationListBox.Items = string(1:length(app.constraints));
                app.SelectionIterationListBox.Value = app.SelectionIterationListBox.Items(end);
                app.selectedIter = str2double(app.SelectionIterationListBox.Value);
                
                % Show selection history
                cla(app.UIAxesSelectionHistory); showHistory(app.constraints,app.classification,app.d{app.iter},app.UIAxesSelectionHistory);
                cla(app.UIAxesSelectioNHistory2); showHistory(app.constraints,app.classification,app.d{app.iter},app.UIAxesSelectioNHistory2);
            else
                app.consoleTextArea.Value = 'No prototypes selected!';
            end
            app.selectionLabels = [];
            app.UIAxesSelectionHistory.Visible = true;
            app.UIAxesMeanDrift.Visible = true;
            app.UIAxesSimilaritySpace.Visible = true;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if ~isempty(app.constraints(app.selectedIter))
                app.constraints(app.selectedIter) = [];
                app.selectedPrototypes(app.selectedIter) = [];
                
                if length(app.classification) ~= app.selectedIter
                    app.classification(app.selectedIter) = [];
                    app.d(app.selectedIter) = [];
                    app.p(app.selectedIter) = [];
                    app.map(app.selectedIter) = [];
                else
                    app.AddSelectionButton.Enable = true;
                end
                app.iter = length(app.map);
                % Reconfigure GUI
                app.SelectionIterationListBox.Items = string(1:app.iter);
                app.SelectionIterationListBox.Value = int2str(app.iter);
                app.selectedIter = app.iter;
                % Revisualize
                cla(app.UIAxesSelectionHistory); showHistory(app.constraints,app.classification,app.d{app.iter},app.UIAxesSelectionHistory);
                cla(app.UIAxesSelectioNHistory2); showHistory(app.constraints,app.classification,app.d{app.iter},app.UIAxesSelectioNHistory2);
                numRows = ceil( size(app.classification{app.iter}.protoX,1).^(2/3) );
                for i=1:size(app.classification{app.iter}.protoX,1)
                    placement(i,:) = [mod(i-1,numRows) floor((i-1)/numRows)]*app.d{app.iter}.spacer;
                end
                cla(app.UIAxesPrototypesForSelection);
                showPhenotype(app.UIAxesPrototypesForSelection,app.d{app.iter},app.classification{app.iter}.protoX,placement);
                axis(app.UIAxesPrototypesForSelection, 'equal');
            end
        end

        % Value changed function: SelectionIterationListBox
        function SelectionIterationListBoxValueChanged(app, event)
            value = app.SelectionIterationListBox.Value;
            app.selectedIter = str2double(value);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %% Workarounds because UIAxes objects are not allowed to have callbacks yet. Maybe in Matlab 2019b?
            pos = event.Source.CurrentPoint;
            %  Click on prototypes and select
            if strcmp(app.TabGroup.SelectedTab.Title,'Analysis/Selection') && strcmp(app.AddSelectionButton.Enable,'on')
                figPos = app.UIAxesPrototypesForSelection.InnerPosition;
                if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                    if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                        relPos = app.UIAxesPrototypesForSelection.CurrentPoint(1,1:2);
                        polyAxes = app.UIAxesPrototypesForSelection.Children;
                        polys = []; numVerts = 0;
                        for i=1:length(polyAxes)
                            polys = [polys;polyAxes(i).Shape.Vertices];
                            numVerts(i+1) = numVerts(i) + size(polyAxes(i).Shape.Vertices,1);
                        end
                        numVerts(1) = [];
                        distances = pdist2(relPos,polys);
                        [~,closest] = min(distances);
                        closestPrototypes = closest<=numVerts; closestPrototypes = fliplr(closestPrototypes);
                        closestPrototypes = find(closestPrototypes);
                        closestPrototypes = closestPrototypes(end);
                        
                        if isempty(app.selectionLabels)
                            app.selectionLabels = zeros(1,size(app.classification{app.iter}.protoX,1));
                        end
                        app.selectionLabels(closestPrototypes) = mod(app.selectionLabels(closestPrototypes)+1,2);
                        
                        % Overwrite selected prototypes with highlights
                        app.UIAxesPrototypesForSelection.cla;
                        numRows = ceil( size(app.classification{app.iter}.protoX,1).^(2/3) );
                        for i=1:size(app.classification{app.iter}.protoX,1)
                            placement(i,:) = [mod(i-1,numRows) floor((i-1)/numRows)]*app.d{app.iter}.spacer;
                        end
                        
                        showPhenotype(app.UIAxesPrototypesForSelection,app.d{app.iter},app.classification{app.iter}.protoX,placement,app.selectionLabels+1);
                        axis(app.UIAxesPrototypesForSelection, 'equal');
                    end
                end
            end
            
            %  Click on prototypes and deselect
            if strcmp(app.TabGroup.SelectedTab.Title,'Analysis/Selection') && strcmp(app.AddSelectionButton.Enable,'on') && ...
                    ~isempty(app.selectedPrototypes) && length(app.selectedPrototypes) == app.iter && ...
                    ~isempty(app.selectedPrototypes{app.iter})
                figPos = app.UIAxesSelectioNHistory2.InnerPosition;
                if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                    if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                        if ~isempty(app.selectedPrototypes{app.iter})
                            relPos = app.UIAxesSelectioNHistory2.CurrentPoint(1,1:2);
                            polyAxes = app.UIAxesSelectioNHistory2.Children;
                            polys = []; numVerts = 0;
                            for i=1:length(polyAxes)
                                polys = [polys;polyAxes(i).Shape.Vertices];
                                numVerts(i+1) = numVerts(i) + size(polyAxes(i).Shape.Vertices,1);
                            end
                            numVerts(1) = [];
                            distances = pdist2(relPos,polys);
                            [~,closest] = min(distances);
                            closestPrototypes = closest<=numVerts; closestPrototypes = fliplr(closestPrototypes);
                            closestPrototypes = find(closestPrototypes);
                            app.selectedPrototypes{app.iter}(closestPrototypes(end)) = [];
                            % Row and col placement
                            cla(app.UIAxesSelectioNHistory2);
                            if ~isempty(app.selectedPrototypes{app.iter})
                                for i=1:size(app.classification{app.iter}.protoX(app.selectedPrototypes{app.iter},:),1)
                                    placement(i,:) = [(i-1)*app.d{app.iter}.spacer 0];
                                end
                                cla(app.UIAxesSelectioNHistory2);
                                showPhenotype(app.UIAxesSelectioNHistory2,app.d{app.iter},app.classification{app.iter}.protoX(app.selectedPrototypes{app.iter},:),placement);
                                axis(app.UIAxesSelectioNHistory2,'equal');
                            end
                        end
                    end
                end
            end
        end

        % Value changed function: surrogateassistanceCheckBox
        function surrogateassistanceCheckBoxValueChanged(app, event)
            value = app.surrogateassistanceCheckBox.Value;
            app.sa = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Enable = 'off';
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Enable = 'off';
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Enable = 'off';
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DomainTab = uitab(app.TabGroup);
            app.DomainTab.Title = 'Domain';
            app.UIAxesExampleSolution = uiaxes(app.DomainTab);
            title(app.UIAxesExampleSolution, 'Example Solution')
            xlabel(app.UIAxesExampleSolution, 'X')
            ylabel(app.UIAxesExampleSolution, 'Y')
            app.DomainConfigurationPanel = uipanel(app.DomainTab);
            app.DomainConfigurationPanel.Title = 'Domain Configuration';
            app.domainDropDownLabel = uilabel(app.DomainConfigurationPanel);
            app.domainDropDownLabel.Text = 'domain';
            app.domainDropDown = uidropdown(app.DomainConfigurationPanel);
            app.domainDropDown.Items = {'npoly_ffd'};
            app.domainDropDown.ValueChangedFcn = createCallbackFcn(app, @domainDropDownValueChanged, true);
            app.domainDropDown.Tooltip = {'Select the domain'};
            app.domainDropDown.Value = 'npoly_ffd';
            app.loadeddomainLabel = uilabel(app.DomainConfigurationPanel);
            app.loadeddomainLabel.Text = 'loaded domain: ';
            app.DOFDropDownLabel = uilabel(app.DomainConfigurationPanel);
            app.DOFDropDownLabel.Text = 'DOF';
            app.DOFDropDown = uidropdown(app.DomainConfigurationPanel);
            app.DOFDropDown.Items = {'8', '12', '16', '20', '24', '28', '32', '36'};
            app.DOFDropDown.ValueChangedFcn = createCallbackFcn(app, @DOFDropDownValueChanged, true);
            app.DOFDropDown.Tooltip = {'Set number of degrees of freedom'};
            app.DOFDropDown.Value = '16';
            app.DescriptionTextAreaLabel = uilabel(app.DomainTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.DomainTab);
            app.DescriptionTextArea.Editable = 'off';
            app.UIAxesHighFitnessSolution = uiaxes(app.DomainTab);
            title(app.UIAxesHighFitnessSolution, 'Example')
            xlabel(app.UIAxesHighFitnessSolution, 'X')
            ylabel(app.UIAxesHighFitnessSolution, 'Y')
            app.IlluminationTab = uitab(app.TabGroup);
            app.IlluminationTab.Title = 'Illumination';
            app.QDConfigurationPanel = uipanel(app.IlluminationTab);
            app.QDConfigurationPanel.Title = 'QD Configuration';
            app.archiveDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.archiveDropDownLabel.Text = 'archive';
            app.archiveDropDown = uidropdown(app.QDConfigurationPanel);
            app.archiveDropDown.Items = {'grid', 'voronoi', ''};
            app.archiveDropDown.ValueChangedFcn = createCallbackFcn(app, @archiveDropDownValueChanged, true);
            app.archiveDropDown.Tooltip = {'Set QD archive type'};
            app.archiveDropDown.Value = 'grid';
            app.nogenerationsEditFieldLabel = uilabel(app.QDConfigurationPanel);
            app.nogenerationsEditFieldLabel.Text = 'no. generations';
            app.nogenerationsEditField = uieditfield(app.QDConfigurationPanel, 'numeric');
            app.nogenerationsEditField.Limits = [1 100000];
            app.nogenerationsEditField.RoundFractionalValues = 'on';
            app.nogenerationsEditField.ValueChangedFcn = createCallbackFcn(app, @nogenerationsEditFieldValueChanged, true);
            app.nogenerationsEditField.Value = 100;
            app.featurexaxisDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.featurexaxisDropDownLabel.Text = 'feature x axis';
            app.featurexaxisDropDown = uidropdown(app.QDConfigurationPanel);
            app.featurexaxisDropDown.Items = {'default'};
            app.featurexaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featurexaxisDropDownValueChanged, true);
            app.featurexaxisDropDown.Tooltip = {'Select features'};
            app.featurexaxisDropDown.Value = 'default';
            app.featureyaxisDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.featureyaxisDropDownLabel.Text = 'feature y axis';
            app.featureyaxisDropDown = uidropdown(app.QDConfigurationPanel);
            app.featureyaxisDropDown.Items = {'default'};
            app.featureyaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featureyaxisDropDownValueChanged, true);
            app.featureyaxisDropDown.Tooltip = {'Select features'};
            app.featureyaxisDropDown.Value = 'default';
            app.surrogateassistanceCheckBox = uicheckbox(app.QDConfigurationPanel);
            app.surrogateassistanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @surrogateassistanceCheckBoxValueChanged, true);
            app.surrogateassistanceCheckBox.Tooltip = {'When available you can replace most'; 'precise evaluations with a surrogate model. '; 'This will split up the Illumination '; 'phase in a training plus an illumination phase.'};
            app.surrogateassistanceCheckBox.Text = 'surrogate assistance';
            app.resolutionSliderLabel = uilabel(app.QDConfigurationPanel);
            app.resolutionSliderLabel.Text = 'resolution';
            app.resolutionSlider = uislider(app.QDConfigurationPanel);
            app.resolutionSlider.Limits = [0 10];
            app.resolutionSlider.ValueChangedFcn = createCallbackFcn(app, @resolutionSliderValueChanged, true);
            app.resolutionSlider.Tooltip = {'Set resolution. Warning: the resolution is interpreted depending on the type of archive'};
            app.resolutionSlider.Value = 2;
            app.mutationstrengthLabel = uilabel(app.QDConfigurationPanel);
            app.mutationstrengthLabel.Text = {'mutation'; 'strength'};
            app.mutationstrengthSlider = uislider(app.QDConfigurationPanel);
            app.mutationstrengthSlider.Limits = [0.1 1];
            app.mutationstrengthSlider.MajorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.mutationstrengthSlider.ValueChangedFcn = createCallbackFcn(app, @mutationstrengthSliderValueChanged, true);
            app.mutationstrengthSlider.MinorTicks = [0.2 0.4 0.6 0.8 1];
            app.mutationstrengthSlider.Tooltip = {'Set resolution. Warning: the resolution is interpreted depending on the type of archive'};
            app.mutationstrengthSlider.Value = 0.1;
            app.QDcommandsPanel = uipanel(app.IlluminationTab);
            app.QDcommandsPanel.Title = 'QD commands';
            app.RunQDButton = uibutton(app.QDcommandsPanel, 'push');
            app.RunQDButton.ButtonPushedFcn = createCallbackFcn(app, @RunQDButtonPushed, true);
            app.RunQDButton.Tooltip = {'QD is started when pressing this button. '; 'The phenotypic map will be updated every n generations. '; 'After QD is done'; ' the phenotypes will be shown to the right.'};
            app.RunQDButton.Text = 'Run QD';
            app.RedrawMapButton = uibutton(app.QDcommandsPanel, 'push');
            app.RedrawMapButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawMapButtonPushed, true);
            app.RedrawMapButton.Tooltip = {'Redraw the map without restarting QD.'; 'You can use this to analyze the map based on a different feature set.'};
            app.RedrawMapButton.Text = 'Redraw Map';
            app.UIAxesPhenotypicMap = uiaxes(app.IlluminationTab);
            title(app.UIAxesPhenotypicMap, 'Phenotypic Map')
            xlabel(app.UIAxesPhenotypicMap, 'X')
            ylabel(app.UIAxesPhenotypicMap, 'Y')
            app.UIAxesSelectionHistory = uiaxes(app.IlluminationTab);
            title(app.UIAxesSelectionHistory, 'Selection History')
            xlabel(app.UIAxesSelectionHistory, '')
            ylabel(app.UIAxesSelectionHistory, '')
            app.UIAxesSelectionHistory.Visible = 'off';
            app.UIAxesTrueFitness = uiaxes(app.IlluminationTab);
            title(app.UIAxesTrueFitness, 'True Fitness')
            xlabel(app.UIAxesTrueFitness, 'X')
            ylabel(app.UIAxesTrueFitness, 'Y')
            app.UIAxesSimilaritySpace = uiaxes(app.IlluminationTab);
            title(app.UIAxesSimilaritySpace, 'View from Similarity Space')
            xlabel(app.UIAxesSimilaritySpace, '')
            ylabel(app.UIAxesSimilaritySpace, '')
            app.UIAxesSimilaritySpace.XTick = [];
            app.UIAxesSimilaritySpace.YTick = [];
            app.UIAxesSimilaritySpace.Visible = 'off';
            app.UIAxesMeanFitness = uiaxes(app.IlluminationTab);
            title(app.UIAxesMeanFitness, 'Total Fitness (QD Fitness)')
            xlabel(app.UIAxesMeanFitness, 'Generations')
            ylabel(app.UIAxesMeanFitness, 'Fitness')
            app.UIAxesMeanDrift = uiaxes(app.IlluminationTab);
            title(app.UIAxesMeanDrift, 'Mean User Selection Drift')
            xlabel(app.UIAxesMeanDrift, 'Generations')
            ylabel(app.UIAxesMeanDrift, 'Drift')
            app.UIAxesMeanDrift.Visible = 'off';
            app.VisualizeDriftCheckBox = uicheckbox(app.IlluminationTab);
            app.VisualizeDriftCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizeDriftCheckBoxValueChanged, true);
            app.VisualizeDriftCheckBox.Text = 'Visualize Drift';
            app.UIAxesAcquisition = uiaxes(app.IlluminationTab);
            title(app.UIAxesAcquisition, 'Acquisition')
            xlabel(app.UIAxesAcquisition, 'X')
            ylabel(app.UIAxesAcquisition, 'Y')
            app.UserSelectionPressurePanel = uipanel(app.IlluminationTab);
            app.UserSelectionPressurePanel.Title = 'User Selection Pressure';
            app.SliderLabel = uilabel(app.UserSelectionPressurePanel);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.UserSelectionPressurePanel);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 5 10];
            app.Slider.MajorTickLabels = {'0', '5', '10'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tooltip = {'The weight used in the soft constraints. '; 'Probably you do not need to change this.'; 'However if you do'; ' you should expect an increase of similarity between '; 'produced solutions by QD and the user''s selection.'};
            app.Slider.Value = 2;
            app.ExplicitTab = uitab(app.TabGroup);
            app.ExplicitTab.Title = 'Explicit';
            app.UIAxesExplicitPhenotypesLarge = uiaxes(app.ExplicitTab);
            title(app.UIAxesExplicitPhenotypesLarge, 'Expressed Phenotypes')
            xlabel(app.UIAxesExplicitPhenotypesLarge, 'X')
            ylabel(app.UIAxesExplicitPhenotypesLarge, 'Y')
            app.AnalysisSelectionTab = uitab(app.TabGroup);
            app.AnalysisSelectionTab.Title = 'Analysis/Selection';
            app.AnalysisPanel = uipanel(app.AnalysisSelectionTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.GeneticPrototypingButton = uibutton(app.AnalysisPanel, 'push');
            app.GeneticPrototypingButton.ButtonPushedFcn = createCallbackFcn(app, @GeneticPrototypingButtonPushed, true);
            app.GeneticPrototypingButton.Text = 'Genetic Prototyping';
            app.noprototypesSliderLabel = uilabel(app.AnalysisPanel);
            app.noprototypesSliderLabel.Text = 'no. prototypes';
            app.noprototypesSlider = uislider(app.AnalysisPanel);
            app.noprototypesSlider.Limits = [1 40];
            app.noprototypesSlider.MajorTicks = [1 10 20 30 40];
            app.noprototypesSlider.MinorTicks = [5 15 25 35];
            app.noprototypesSlider.Value = 10;
            app.UIAxesClustersInSimilarity = uiaxes(app.AnalysisSelectionTab);
            title(app.UIAxesClustersInSimilarity, 'Similarity Space')
            xlabel(app.UIAxesClustersInSimilarity, '')
            ylabel(app.UIAxesClustersInSimilarity, '')
            app.UIAxesClustersInSimilarity.XTick = [];
            app.UIAxesClustersInSimilarity.YTick = [];
            app.UserSelectionPanel = uipanel(app.AnalysisSelectionTab);
            app.UserSelectionPanel.Title = 'User Selection';
            app.AddSelectionButton = uibutton(app.UserSelectionPanel, 'push');
            app.AddSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSelectionButtonPushed, true);
            app.AddSelectionButton.Text = 'Add Selection';
            app.RemoveButton = uibutton(app.UserSelectionPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.consoleTextAreaLabel = uilabel(app.AnalysisSelectionTab);
            app.consoleTextAreaLabel.Enable = 'off';
            app.consoleTextAreaLabel.Text = 'console';
            app.consoleTextArea = uitextarea(app.AnalysisSelectionTab);
            app.consoleTextArea.Editable = 'off';
            app.consoleTextArea.Enable = 'off';
            app.SelectionIterationListBoxLabel = uilabel(app.AnalysisSelectionTab);
            app.SelectionIterationListBoxLabel.Text = {'Selection'; 'Iteration'};
            app.SelectionIterationListBox = uilistbox(app.AnalysisSelectionTab);
            app.SelectionIterationListBox.Items = {'1'};
            app.SelectionIterationListBox.ValueChangedFcn = createCallbackFcn(app, @SelectionIterationListBoxValueChanged, true);
            app.SelectionIterationListBox.Value = '1';
            app.UIAxesPrototypesForSelection = uiaxes(app.AnalysisSelectionTab);
            title(app.UIAxesPrototypesForSelection, 'Prototypes')
            xlabel(app.UIAxesPrototypesForSelection, '')
            ylabel(app.UIAxesPrototypesForSelection, '')
            app.UIAxesPrototypesForSelection.XLim = [0 2.5];
            app.UIAxesSelectioNHistory2 = uiaxes(app.AnalysisSelectionTab);
            title(app.UIAxesSelectioNHistory2, 'Selection History')
            xlabel(app.UIAxesSelectioNHistory2, 'Iteration')
            ylabel(app.UIAxesSelectioNHistory2, '')
            app.UIAxesSelectioNHistory2.XLim = [-1 20];
            app.UIAxesSelectioNHistory2.YLim = [-2 2];
            app.UIAxesSelectioNHistory2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demoGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end